classdef SwingDAQ < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        ClearFigureButton           matlab.ui.control.Button
        Chan15CheckBox              matlab.ui.control.CheckBox
        Chan14CheckBox              matlab.ui.control.CheckBox
        Chan13CheckBox              matlab.ui.control.CheckBox
        Chan12CheckBox              matlab.ui.control.CheckBox
        Chan11CheckBox              matlab.ui.control.CheckBox
        Chan10CheckBox              matlab.ui.control.CheckBox
        Chan9CheckBox               matlab.ui.control.CheckBox
        Chan8CheckBox               matlab.ui.control.CheckBox
        Chan7CheckBox               matlab.ui.control.CheckBox
        Chan6CheckBox               matlab.ui.control.CheckBox
        Chan5CheckBox               matlab.ui.control.CheckBox
        Chan4CheckBox               matlab.ui.control.CheckBox
        Chan3CheckBox               matlab.ui.control.CheckBox
        Chan2CheckBox               matlab.ui.control.CheckBox
        Chan1CheckBox               matlab.ui.control.CheckBox
        DisconnectButton            matlab.ui.control.Button
        BrowseButton                matlab.ui.control.Button
        FilenametosaveacquireddataEditField  matlab.ui.control.EditField
        FilenametosaveacquireddataEditFieldLabel  matlab.ui.control.Label
        ConnectButton               matlab.ui.control.Button
        EndDAQButton                matlab.ui.control.Button
        StartDAQButton              matlab.ui.control.Button
        COMPortforArduinoEditField  matlab.ui.control.EditField
        COMPortforArduinoEditFieldLabel  matlab.ui.control.Label
        UIAxes                      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        myArduino % serial port connected to Arduino
        checkBoxValues % boolean values of checkboxes
    end

    properties (Constant, Access = public)
        plotBlockSize = 10;
        numChannels = 15;
    end

    events
        AcquiredDataBlock
    end

    methods (Access = public)
        function channelsToPlot = getChannelsToPlot(app)
            channelsToPlot = app.checkBoxValues;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % This callback is to be called during initialization of the
            % app object; this function is created by clicking on the add
            % Callback button in the editor and selecting SwingDAQ as the
            % component
            app.checkBoxValues = false(1,app.numChannels);
        end

        % Button pushed function: StartDAQButton
        function StartDAQButtonPushed(app, event)
            app.myArduino.UserData = struct("Data",[],"Count",0);
            configureCallback(app.myArduino,"terminator",...
                            @(src,evt)(readArduino(src,evt,app)));
        end

        % Button pushed function: EndDAQButton
        function EndDAQButtonPushed(app, event)
            configureCallback(app.myArduino, "off");
            acquiredData = app.myArduino.UserData.Data;
            filename = app.FilenametosaveacquireddataEditField.Value;
            save(filename,"acquiredData");
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            comport = app.COMPortforArduinoEditField.Value;
            app.myArduino = serialport(comport, 57600);
            configureTerminator(app.myArduino,"CR/LF");

            addlistener(app,'AcquiredDataBlock',...
                @(src,evt)plotAcquiredData(src,evt,...
                                           app.myArduino,app.UIAxes));
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            [file,path] = uiputfile("*.mat");
            app.FilenametosaveacquireddataEditField.Value = ...
                fullfile(path,file);
        end

        % Button pushed function: DisconnectButton
        function DisconnectButtonPushed(app, event)
            app.myArduino.delete;
        end

        % Value changed function: Chan1CheckBox
        function Chan1CheckBoxValueChanged(app, event)
            value = app.Chan1CheckBox.Value;
            app.checkBoxValues(1) = value;
        end

        % Value changed function: Chan2CheckBox
        function Chan2CheckBoxValueChanged(app, event)
            value = app.Chan2CheckBox.Value;
            app.checkBoxValues(2) = value;
        end

        % Value changed function: Chan3CheckBox
        function Chan3CheckBoxValueChanged(app, event)
            value = app.Chan3CheckBox.Value;
            app.checkBoxValues(3) = value;
        end

        % Value changed function: Chan10CheckBox
        function Chan10CheckBoxValueChanged(app, event)
            value = app.Chan10CheckBox.Value;
            app.checkBoxValues(10) = value;
        end

        % Value changed function: Chan11CheckBox
        function Chan11CheckBoxValueChanged(app, event)
            value = app.Chan11CheckBox.Value;
            app.checkBoxValues(11) = value;
        end

        % Button pushed function: ClearFigureButton
        function ClearFigureButtonPushed(app, event)
            cla(app.UIAxes);
        end

        % Value changed function: Chan12CheckBox
        function Chan12CheckBoxValueChanged(app, event)
            value = app.Chan12CheckBox.Value;
            app.checkBoxValues(12) = value;
        end

        % Value changed function: Chan13CheckBox
        function Chan13CheckBoxValueChanged(app, event)
            value = app.Chan13CheckBox.Value;
            app.checkBoxValues(13) = value;
        end

        % Value changed function: Chan14CheckBox
        function Chan14CheckBoxValueChanged(app, event)
            value = app.Chan14CheckBox.Value;
            app.checkBoxValues(14) = value;
        end

        % Value changed function: Chan15CheckBox
        function Chan15CheckBoxValueChanged(app, event)
            value = app.Chan15CheckBox.Value;
            app.checkBoxValues(15) = value;
        end

        % Value changed function: Chan4CheckBox
        function Chan4CheckBoxValueChanged(app, event)
            value = app.Chan4CheckBox.Value;
            app.checkBoxValues(4) = value;
        end

        % Value changed function: Chan5CheckBox
        function Chan5CheckBoxValueChanged(app, event)
            value = app.Chan5CheckBox.Value;
            app.checkBoxValues(5) = value;
        end

        % Value changed function: Chan6CheckBox
        function Chan6CheckBoxValueChanged(app, event)
            value = app.Chan6CheckBox.Value;
            app.checkBoxValues(6) = value;
        end

        % Value changed function: Chan7CheckBox
        function Chan7CheckBoxValueChanged(app, event)
            value = app.Chan7CheckBox.Value;
            app.checkBoxValues(7) = value;
        end

        % Value changed function: Chan8CheckBox
        function Chan8CheckBoxValueChanged(app, event)
            value = app.Chan8CheckBox.Value;
            app.checkBoxValues(8) = value;
        end

        % Value changed function: Chan9CheckBox
        function Chan9CheckBoxValueChanged(app, event)
            value = app.Chan9CheckBox.Value;
            app.checkBoxValues(9) = value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Acquired Data')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.COMPortforArduinoEditFieldLabel = uilabel(app.UIFigure);
            app.COMPortforArduinoEditFieldLabel.Text = 'COM Port for Arduino';
            app.COMPortforArduinoEditField = uieditfield(app.UIFigure, 'text');
            app.StartDAQButton = uibutton(app.UIFigure, 'push');
            app.StartDAQButton.ButtonPushedFcn = createCallbackFcn(app, @StartDAQButtonPushed, true);
            app.StartDAQButton.Text = 'Start DAQ';
            app.EndDAQButton = uibutton(app.UIFigure, 'push');
            app.EndDAQButton.ButtonPushedFcn = createCallbackFcn(app, @EndDAQButtonPushed, true);
            app.EndDAQButton.Text = 'End DAQ';
            app.ConnectButton = uibutton(app.UIFigure, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.FilenametosaveacquireddataEditFieldLabel = uilabel(app.UIFigure);
            app.FilenametosaveacquireddataEditFieldLabel.Text = 'Filename to  save acquired data';
            app.FilenametosaveacquireddataEditField = uieditfield(app.UIFigure, 'text');
            app.BrowseButton = uibutton(app.UIFigure, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.DisconnectButton = uibutton(app.UIFigure, 'push');
            app.DisconnectButton.ButtonPushedFcn = createCallbackFcn(app, @DisconnectButtonPushed, true);
            app.DisconnectButton.Text = 'Disconnect';
            app.Chan1CheckBox = uicheckbox(app.UIFigure);
            app.Chan1CheckBox.ValueChangedFcn = createCallbackFcn(app, @Chan1CheckBoxValueChanged, true);
            app.Chan1CheckBox.Text = 'Chan 1';
            app.Chan2CheckBox = uicheckbox(app.UIFigure);
            app.Chan2CheckBox.ValueChangedFcn = createCallbackFcn(app, @Chan2CheckBoxValueChanged, true);
            app.Chan2CheckBox.Text = 'Chan 2';
            app.Chan3CheckBox = uicheckbox(app.UIFigure);
            app.Chan3CheckBox.ValueChangedFcn = createCallbackFcn(app, @Chan3CheckBoxValueChanged, true);
            app.Chan3CheckBox.Text = 'Chan 3';
            app.Chan4CheckBox = uicheckbox(app.UIFigure);
            app.Chan4CheckBox.ValueChangedFcn = createCallbackFcn(app, @Chan4CheckBoxValueChanged, true);
            app.Chan4CheckBox.Text = 'Chan 4';
            app.Chan5CheckBox = uicheckbox(app.UIFigure);
            app.Chan5CheckBox.ValueChangedFcn = createCallbackFcn(app, @Chan5CheckBoxValueChanged, true);
            app.Chan5CheckBox.Text = 'Chan 5';
            app.Chan6CheckBox = uicheckbox(app.UIFigure);
            app.Chan6CheckBox.ValueChangedFcn = createCallbackFcn(app, @Chan6CheckBoxValueChanged, true);
            app.Chan6CheckBox.Text = 'Chan 6';
            app.Chan7CheckBox = uicheckbox(app.UIFigure);
            app.Chan7CheckBox.ValueChangedFcn = createCallbackFcn(app, @Chan7CheckBoxValueChanged, true);
            app.Chan7CheckBox.Text = 'Chan 7';
            app.Chan8CheckBox = uicheckbox(app.UIFigure);
            app.Chan8CheckBox.ValueChangedFcn = createCallbackFcn(app, @Chan8CheckBoxValueChanged, true);
            app.Chan8CheckBox.Text = 'Chan 8';
            app.Chan9CheckBox = uicheckbox(app.UIFigure);
            app.Chan9CheckBox.ValueChangedFcn = createCallbackFcn(app, @Chan9CheckBoxValueChanged, true);
            app.Chan9CheckBox.Text = 'Chan 9';
            app.Chan10CheckBox = uicheckbox(app.UIFigure);
            app.Chan10CheckBox.ValueChangedFcn = createCallbackFcn(app, @Chan10CheckBoxValueChanged, true);
            app.Chan10CheckBox.Text = 'Chan 10';
            app.Chan11CheckBox = uicheckbox(app.UIFigure);
            app.Chan11CheckBox.ValueChangedFcn = createCallbackFcn(app, @Chan11CheckBoxValueChanged, true);
            app.Chan11CheckBox.Text = 'Chan 11';
            app.Chan12CheckBox = uicheckbox(app.UIFigure);
            app.Chan12CheckBox.ValueChangedFcn = createCallbackFcn(app, @Chan12CheckBoxValueChanged, true);
            app.Chan12CheckBox.Text = 'Chan 12';
            app.Chan13CheckBox = uicheckbox(app.UIFigure);
            app.Chan13CheckBox.ValueChangedFcn = createCallbackFcn(app, @Chan13CheckBoxValueChanged, true);
            app.Chan13CheckBox.Text = 'Chan 13';
            app.Chan14CheckBox = uicheckbox(app.UIFigure);
            app.Chan14CheckBox.ValueChangedFcn = createCallbackFcn(app, @Chan14CheckBoxValueChanged, true);
            app.Chan14CheckBox.Text = 'Chan 14';
            app.Chan15CheckBox = uicheckbox(app.UIFigure);
            app.Chan15CheckBox.ValueChangedFcn = createCallbackFcn(app, @Chan15CheckBoxValueChanged, true);
            app.Chan15CheckBox.Text = 'Chan 15';
            app.ClearFigureButton = uibutton(app.UIFigure, 'push');
            app.ClearFigureButton.ButtonPushedFcn = createCallbackFcn(app, @ClearFigureButtonPushed, true);
            app.ClearFigureButton.Text = 'Clear Figure';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SwingDAQ

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end